classdef Reto_Final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        Label_6                        matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        EnergiaperdidaLabel            matlab.ui.control.Label
        yLabel                         matlab.ui.control.Label
        xLabel                         matlab.ui.control.Label
        PosicinLabel                   matlab.ui.control.Label
        VelocidadmaximaLabel           matlab.ui.control.Label
        DuracinLabel                   matlab.ui.control.Label
        TiempoActualLabel              matlab.ui.control.Label
        TiempodeinicioLabel            matlab.ui.control.Label
        ResultadosLabel                matlab.ui.control.Label
        SimulacindeFormula1Label       matlab.ui.control.Label
        DatosdeEntradaPanel            matlab.ui.container.Panel
        CorrerSimulacionButton         matlab.ui.control.Button
        Switch                         matlab.ui.control.ToggleSwitch
        CoeficientedefrccionEditField  matlab.ui.control.NumericEditField
        CoeficientedefrccionEditFieldLabel  matlab.ui.control.Label
        MasaEditField                  matlab.ui.control.NumericEditField
        MasaEditFieldLabel             matlab.ui.control.Label
        VelocidadEditField             matlab.ui.control.NumericEditField
        VelocidadLabel                 matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CorrerSimulacionButton
        function CorrerSimulacionButtonPushed(app, event)
            a = 0.00010262;
            b = -0.040327;
            c = 3.2342;
            d = 261.5886;
            
            f1 = @(x) a * x.^3 + b * x.^2 + c * x + d;
            f2 = @(x) a * x.^3 + b * x.^2 + c * x + d;
            %Maximos y Minimos
            xMax = (- b - sqrt(b^2 - 3  *a *c)) / (3 * a);
            xMin = (- b + sqrt(b^2 - 3 * a*c)) / (3 * a);
            
            yMax = f1(xMax);
            yMin = f1(xMin);
            
            hold on;
            fplot(app.UIAxes,f1,[10,260],"LineWidth",20,"color","#000000");
            fplot(app.UIAxes,f1,[10,260],"LineWidth",5,"color","#ffff00","LineStyle","--");
            hold off;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Pista')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DatosdeEntradaPanel = uipanel(app.UIFigure);
            app.DatosdeEntradaPanel.Title = 'Datos de Entrada';
            app.VelocidadLabel = uilabel(app.DatosdeEntradaPanel);
            app.VelocidadLabel.Text = 'Velocidad:';
            app.VelocidadEditField = uieditfield(app.DatosdeEntradaPanel, 'numeric');
            app.MasaEditFieldLabel = uilabel(app.DatosdeEntradaPanel);
            app.MasaEditFieldLabel.Text = 'Masa:';
            app.MasaEditField = uieditfield(app.DatosdeEntradaPanel, 'numeric');
            app.CoeficientedefrccionEditFieldLabel = uilabel(app.DatosdeEntradaPanel);
            app.CoeficientedefrccionEditFieldLabel.Text = 'Coeficiente de frccion';
            app.CoeficientedefrccionEditField = uieditfield(app.DatosdeEntradaPanel, 'numeric');
            app.Switch = uiswitch(app.DatosdeEntradaPanel, 'toggle');
            app.Switch.Items = {'km/h', 'm/s'};
            app.Switch.Value = 'km/h';
            app.CorrerSimulacionButton = uibutton(app.DatosdeEntradaPanel, 'push');
            app.CorrerSimulacionButton.ButtonPushedFcn = createCallbackFcn(app, @CorrerSimulacionButtonPushed, true);
            app.CorrerSimulacionButton.Text = 'Correr Simulacion';
            app.Panel = uipanel(app.UIFigure);
            app.SimulacindeFormula1Label = uilabel(app.Panel);
            app.SimulacindeFormula1Label.Text = 'Simulación de Formula1';
            app.ResultadosLabel = uilabel(app.Panel);
            app.ResultadosLabel.Text = 'Resultados';
            app.TiempodeinicioLabel = uilabel(app.Panel);
            app.TiempodeinicioLabel.Text = 'Tiempo de inicio:';
            app.TiempoActualLabel = uilabel(app.Panel);
            app.TiempoActualLabel.Text = 'Tiempo Actual:';
            app.DuracinLabel = uilabel(app.Panel);
            app.DuracinLabel.Text = 'Duración:';
            app.VelocidadmaximaLabel = uilabel(app.Panel);
            app.VelocidadmaximaLabel.Text = 'Velocidad maxima: ';
            app.PosicinLabel = uilabel(app.Panel);
            app.PosicinLabel.Text = 'Posición';
            app.xLabel = uilabel(app.Panel);
            app.xLabel.Text = 'x:';
            app.yLabel = uilabel(app.Panel);
            app.yLabel.Text = 'y:';
            app.EnergiaperdidaLabel = uilabel(app.Panel);
            app.EnergiaperdidaLabel.Text = 'Energia perdida:';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.Panel);
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Reto_Final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end